classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CalcularCurvaYButton         matlab.ui.control.Button
        CalcularCurvaXButton         matlab.ui.control.Button
        ValorRoLabel                 matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ValorAsLabel                 matlab.ui.control.Label
        Asmm2Label                   matlab.ui.control.Label
        ValorbettaLabel              matlab.ui.control.Label
        BettaLabel                   matlab.ui.control.Label
        RecubrimientoEditField       matlab.ui.control.NumericEditField
        RecubrimientoEditFieldLabel  matlab.ui.control.Label
        APPVerificacinColumnasLabel  matlab.ui.control.Label
        GeometraLabel                matlab.ui.control.Label
        fcEditField                  matlab.ui.control.NumericEditField
        fcEditFieldLabel             matlab.ui.control.Label
        fyMPaEditField               matlab.ui.control.NumericEditField
        fyMPaEditFieldLabel          matlab.ui.control.Label
        ConcretoMPaLabel             matlab.ui.control.Label
        BarrasNEditField             matlab.ui.control.NumericEditField
        BarrasNEditFieldLabel        matlab.ui.control.Label
        FilasejeyEditField           matlab.ui.control.NumericEditField
        FilasejeyEditFieldLabel      matlab.ui.control.Label
        FilasejexEditField           matlab.ui.control.NumericEditField
        FilasejexEditFieldLabel      matlab.ui.control.Label
        BaseEditField                matlab.ui.control.NumericEditField
        BaseEditFieldLabel           matlab.ui.control.Label
        AlturaEditField              matlab.ui.control.NumericEditField
        AlturaEditFieldLabel         matlab.ui.control.Label
        RefuerzoLabel                matlab.ui.control.Label
        DimensionescmLabel           matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X="Cambio texto" % Variable X
        b=0 %Base seccion
        h=0 %Altura seccion
        d=0 %Recubrimiento seccion
        filas_x=0 %filas barras ejex
        filas_y=0 %filas barras ejey
        spacing_x=0 %espaciamiento barras en x
        spacing_y=0 %espaciamiento barras en y
        bar_size=0 %numero de barras acero refuerzo
        fc=0 %resistencia compresion concreto
        fy=0 %esfuerzo de fluencia
        As=0 %area de acero de refuerzo
        bars_matrix=[] %matriz barras
        betta = 0 %factor beta concreto
    end
    
    methods (Access = private)  

        function[]=calc_geom(app)

            if (app.filas_x ~= 0 && app.filas_y ~=0)
                app.spacing_y = (app.h - 2*app.d)/(app.filas_y-1);
                app.spacing_x = (app.b - 2*app.d)/(app.filas_x-1);
            end

            if (app.h~=0 && app.b~=0)
                app.plot_geom()
            end  

        end

        function[]=plot_geom(app)
           cla(app.UIAxes,'reset')
           rectangle(app.UIAxes,'Position',[0,0,app.b,app.h], 'FaceColor',[0.91,0.929,0.961])
           hold(app.UIAxes, "on")

           if (app.filas_x ~= 0 && app.filas_y ~=0 && app.bar_size ~=0)
               app.plot_bars() 
           end
                     
           axis(app.UIAxes, 'equal')

           disp(app.bars_matrix)

           bars_qty = sum(app.bars_matrix, "all");
           app.As = app.area_bars(bars_qty);
           app.ValorAsLabel.Text = string(app.As);
           app.calc_ro()
        end

        function[]=plot_bars(app)
            app.bars_matrix = [];
            for i=1:app.filas_y
                for j=1:app.filas_x
                    if (i==1 || i==app.filas_y)
                        scatter(app.UIAxes, app.d+(j-1)*app.spacing_x, app.d+(i-1)*app.spacing_y,app.bar_size*254/16,"red","filled")
                        app.bars_matrix(i,j) = 1;
                    elseif (j==1 || j==app.filas_x)
                        scatter(app.UIAxes, app.d+(j-1)*app.spacing_x, app.d+(i-1)*app.spacing_y,app.bar_size*254/16,"red","filled")
                        app.bars_matrix(i,j) = 1;
                    end
                end
            end
        end

        function[total_area]=area_bars(app, bars_qty)
          total_area = pi/4*(app.bar_size*25.4/8)^2*bars_qty;
        end

        function[]=calc_betta(app)
            if (app.fc <=28)
                app.betta = 0.85;
            else
                be = 0.85-0.05 *((app.fc-28)/7);
                if (be<0.65)
                    app.betta = 0.65;
                else
                    app.betta = be;
                end
            end
        app.ValorbettaLabel.Text = string(app.betta);
        end

        function[]=calc_ro(app)
            ro = app.As /(app.b*10*app.h*10);
            app.ValorRoLabel.Text=string(ro);
        end

        function[phi]=calc_phi(~,es,c,h,d)
            %medidas deben ingresar en mm
            if (es>=-0.0021)
                phi = 0.65;
            else
                phi_pre = 0.65+0.25*(0.003*(h-d-c)/c-0.002)/0.003;
                if phi_pre >=0.9
                    phi = 0.9;
                else
                    phi = phi_pre;
                end
            end
        end

        function[phi]=calc_phi2(~,es)
            if (es<=0.0021)
                phi = 0.65;
            elseif(es>=0.005)
                phi = 0.9;
            else
                phi = 0.65+(es-0.002)*(250/3);
            end
        end

        function[brazo]=calc_brazo(~,space,h,d)
            %medidas deben ingresas en m
            brazo = h/2-d-space;
        end

        function[a]=calc_a(app)
            a = 0.85*app.fc/1000*(app.h*10*app.b*10-app.As)+app.As*app.fy/1000;
        end

        function[]=calc_curvas(app,eje)
            Pn_vect = [];
            phiPn_vect = [];
            Mn_vect =[];
            phiMn_vect = [];
            if (eje == "x")
               altura = app.b;
               base = app.h;
               ejes = app.filas_x;
               suma_refuerzo = sum(app.bars_matrix);
            else
                altura =app.h;
                base = app.b;
                ejes =app.filas_y;
                suma_refuerzo = sum(app.bars_matrix,2);
            end
            Cmax = floor(altura - app.d);

            pos = 1;

            for c=Cmax*10:-5:0
                es_vect = zeros(1,ejes);
                fs_vect = zeros(1,ejes);
                cs_vect = zeros(1,ejes);
                arm_vect = zeros(1,ejes);

                for j=1:1:ejes
                    es = 0.003*(c-app.d*10-(j-1)*(app.spacing_x*10))/c;
                    if (es >= 0.0021)
                        fs = app.fy;
                    elseif (es<=-0.0021)
                        fs = -app.fy;
                    else
                        fs = es *200000;
                    end

                    cs = fs/1000 * suma_refuerzo(j)*((app.bar_size/8*25.4)^2*pi/4);

                    arm = app.calc_brazo((j-1)*(app.spacing_x/100), altura/100, app.d/100);

                    es_vect(j) = es;
                    fs_vect(j) = fs;
                    cs_vect(j) = cs;
                    arm_vect(j) = arm;
                end

                a = app.betta * c;
                Cc = 0.85*app.fc/1000*a*(base*10);

                Pn = sum(cs_vect) + Cc;
                Mn = sum(cs_vect .* arm_vect)+Cc*(altura*10/2000-a/2000);

                % phi = app.calc_phi(es_vect(ejes),c,altura*10,app.d*10);
                phi = app.calc_phi(es_vect(ejes)*-1);

                Pn_vect(pos)=Pn;
                Mn_vect(pos)=Mn;
                phiPn_vect(pos)=Pn * phi;
                phiMn_vect(pos)=Mn*phi;

                pos = pos+1;
            end
            a = app.calc_a();
            Pn_vect = [a, Pn_vect];
            phiPn_vect = [a*0.65, phiPn_vect];
            Mn_vect = [0, Mn_vect];
            phiMn_vect=[0, phiMn_vect];

            if (eje =="x")
                cla(app.UIAxes2,'reset')
                plot(app.UIAxes2,Mn_vect,Pn_vect,phiMn_vect,phiPn_vect)
                xlabel(app.UIAxes2, "MX (kN m)")
                ylabel(app.UIAxes2, "Pn (kN)")
                legend(app.UIAxes2, "Mx,Pn", "PhiMx, PhiPn")
                axis(app.UIAxes2, [0, inf, -inf, inf])
                grid(app.UIAxes2, "on")
            elseif (eje == "y")
                cla(app.UIAxes3,'reset')
                plot(app.UIAxes3,Mn_vect,Pn_vect,phiMn_vect,phiPn_vect)
                xlabel(app.UIAxes3, "MY (kN m)")
                ylabel(app.UIAxes3, "Pn (kN)")
                legend(app.UIAxes3, "My,Pn", "PhiMy, PhiPn")
                axis(app.UIAxes3, [0, inf, -inf, inf])
                grid(app.UIAxes3, "on")
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AlturaEditField
        function AlturaEditFieldValueChanged(app, event)
            value = app.AlturaEditField.Value;
            app.h = value;
            app.calc_geom()
        end

        % Value changed function: BaseEditField
        function BaseEditFieldValueChanged(app, event)
            value = app.BaseEditField.Value;
            app.b = value;
            app.calc_geom()
        end

        % Value changed function: FilasejeyEditField
        function FilasejeyEditFieldValueChanged(app, event)
            value = app.FilasejeyEditField.Value;
            app.filas_y = value;
            app.calc_geom()
        end

        % Value changed function: FilasejexEditField
        function FilasejexEditFieldValueChanged(app, event)
            value = app.FilasejexEditField.Value;
            app.filas_x = value;
            app.calc_geom()
        end

        % Value changed function: RecubrimientoEditField
        function RecubrimientoEditFieldValueChanged(app, event)
            value = app.RecubrimientoEditField.Value;
            app.d = value;
            app.calc_geom()
        end

        % Value changed function: BarrasNEditField
        function BarrasNEditFieldValueChanged(app, event)
            value = app.BarrasNEditField.Value;
            app.bar_size = value;
            app.calc_geom()
        end

        % Value changed function: fyMPaEditField
        function fyMPaEditFieldValueChanged(app, event)
            value = app.fyMPaEditField.Value;
            app.fy = value;
        end

        % Value changed function: fcEditField
        function fcEditFieldValueChanged(app, event)
            value = app.fcEditField.Value;
            app.fc = value;
            app.calc_betta()
        end

        % Button pushed function: CalcularCurvaXButton
        function CalcularCurvaXButtonPushed(app, event)
            app.calc_curvas("x")
        end

        % Button pushed function: CalcularCurvaYButton
        function CalcularCurvaYButtonPushed(app, event)
            app.calc_curvas("y")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Geometría resultante')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Curva MX, PX')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Curva MY, PY')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.DimensionescmLabel = uilabel(app.UIFigure);
            app.DimensionescmLabel.Text = 'Dimensiones (cm)';
            app.RefuerzoLabel = uilabel(app.UIFigure);
            app.RefuerzoLabel.Text = 'Refuerzo';
            app.AlturaEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturaEditField.ValueChangedFcn = createCallbackFcn(app, @AlturaEditFieldValueChanged, true);
            app.BaseEditFieldLabel = uilabel(app.UIFigure);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseEditField.ValueChangedFcn = createCallbackFcn(app, @BaseEditFieldValueChanged, true);
            app.FilasejexEditFieldLabel = uilabel(app.UIFigure);
            app.FilasejexEditFieldLabel.Text = 'Filas eje x';
            app.FilasejexEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilasejexEditField.ValueChangedFcn = createCallbackFcn(app, @FilasejexEditFieldValueChanged, true);
            app.FilasejeyEditFieldLabel = uilabel(app.UIFigure);
            app.FilasejeyEditFieldLabel.Text = 'Filas eje y';
            app.FilasejeyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilasejeyEditField.ValueChangedFcn = createCallbackFcn(app, @FilasejeyEditFieldValueChanged, true);
            app.BarrasNEditFieldLabel = uilabel(app.UIFigure);
            app.BarrasNEditFieldLabel.Text = 'Barras N°';
            app.BarrasNEditField = uieditfield(app.UIFigure, 'numeric');
            app.BarrasNEditField.ValueChangedFcn = createCallbackFcn(app, @BarrasNEditFieldValueChanged, true);
            app.ConcretoMPaLabel = uilabel(app.UIFigure);
            app.ConcretoMPaLabel.Text = 'Concreto (MPa)';
            app.fyMPaEditFieldLabel = uilabel(app.UIFigure);
            app.fyMPaEditFieldLabel.Text = 'fy(MPa)';
            app.fyMPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.fyMPaEditField.ValueChangedFcn = createCallbackFcn(app, @fyMPaEditFieldValueChanged, true);
            app.fcEditFieldLabel = uilabel(app.UIFigure);
            app.fcEditFieldLabel.Text = 'f '' c';
            app.fcEditField = uieditfield(app.UIFigure, 'numeric');
            app.fcEditField.ValueChangedFcn = createCallbackFcn(app, @fcEditFieldValueChanged, true);
            app.GeometraLabel = uilabel(app.UIFigure);
            app.GeometraLabel.Text = 'Geometría';
            app.APPVerificacinColumnasLabel = uilabel(app.UIFigure);
            app.APPVerificacinColumnasLabel.Text = 'APP Verificación Columnas';
            app.RecubrimientoEditFieldLabel = uilabel(app.UIFigure);
            app.RecubrimientoEditFieldLabel.Text = 'Recubrimiento';
            app.RecubrimientoEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecubrimientoEditField.ValueChangedFcn = createCallbackFcn(app, @RecubrimientoEditFieldValueChanged, true);
            app.BettaLabel = uilabel(app.UIFigure);
            app.BettaLabel.Text = 'Betta:';
            app.ValorbettaLabel = uilabel(app.UIFigure);
            app.ValorbettaLabel.Text = 'Valor betta';
            app.Asmm2Label = uilabel(app.UIFigure);
            app.Asmm2Label.Text = 'As (mm2):';
            app.ValorAsLabel = uilabel(app.UIFigure);
            app.ValorAsLabel.Text = 'Valor As';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ρ:';
            app.ValorRoLabel = uilabel(app.UIFigure);
            app.ValorRoLabel.Text = 'Valor Ro';
            app.CalcularCurvaXButton = uibutton(app.UIFigure, 'push');
            app.CalcularCurvaXButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCurvaXButtonPushed, true);
            app.CalcularCurvaXButton.Tooltip = {'Press to calculate curves for Mnx'};
            app.CalcularCurvaXButton.Text = 'Calcular Curva X';
            app.CalcularCurvaYButton = uibutton(app.UIFigure, 'push');
            app.CalcularCurvaYButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCurvaYButtonPushed, true);
            app.CalcularCurvaYButton.Text = 'Calcular Curva Y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end